classdef BeadMapperSimple < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BeadMapperSimpleUIFigure      matlab.ui.Figure
        SaveBeadMapFileButton         matlab.ui.control.Button
        GetBeadMapButton              matlab.ui.control.Button
        DonorAndAcceptorCombined      matlab.ui.control.CheckBox
        AcceptorFileName              matlab.ui.control.EditField
        DonorFileName                 matlab.ui.control.EditField
        AcceptorBeadImageLabel        matlab.ui.control.Label
        DonorBeadImageLabel           matlab.ui.control.Label
        LogBox                        matlab.ui.control.TextArea
        LogTextAreaLabel              matlab.ui.control.Label
        ChooseAcceptorButton          matlab.ui.control.Button
        ChooseDonorButton             matlab.ui.control.Button
        DonorAcceptorRegisteredLabel  matlab.ui.control.Label
        DonorAcceptorOriginalLabel    matlab.ui.control.Label
        OverlayCorrected              matlab.ui.control.UIAxes
        OverlayOriginal               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Results = struct; % Description
        DistanceCutoff = 2;
        EdgePixels = 10;
        Threshold = 10;
        CircularityCutoff = 0.9;
        EllipticityCutoff = 1.5;
        SpotMethod = 'Gaussian';
    end
    
    methods (Access = private)       
        function addLogText(app,Text,varargin)
            % Adds text to log on UI window
            % Args:
            % Text = string or char of text to add to log printout
            % varargin{1}: NewLine = optional boolean specifying whether a new line
            % is added in the log.  Default = true
            if nargin>2
                NewLine = varargin{1};
            else
                NewLine = true;
            end
            if NewLine
                TextToAdd = sprintf("> %s",Text);
                app.LogBox.Value = [TextToAdd; app.LogBox.Value];
            else
                app.LogBox.Value{1} = strcat(app.LogBox.Value{1},Text);
            end
            drawnow;
        end
       
        function Results = calculateError(app,SpotPairs,SpotPairsCorrected,Results)
            % Calculate mean error of colocalization between paired beads before and after correction
            Results.InitialError = calculateColocalizationError(SpotPairs);
            addLogText(app,sprintf('%u bead pairs found.\n',height(SpotPairs.Coords1)),'NewLine');
            str1 = sprintf('Before Correction:\nAvg X Error: %.2f pixels\nAvg Y Error: %.2f pixels\n',...
                            Results.InitialError.X, Results.InitialError.Y);
            addLogText(app,str1,'NewLine');
            Results.FinalError = calculateColocalizationError(SpotPairsCorrected);
            str1 = sprintf('After Correction:\nAvg X Error: %.2f pixels\nAvg Y Error: %.2f pixels\n',...
                Results.FinalError.X, Results.FinalError.Y);
            addLogText(app,str1,'NewLine');
        end
        
        function showChannelOverlay(app,Image1,Image2,Args)
            arguments
                app
                Image1
                Image2
                Args.Axes
            end
            I = zeros(height(Image1),width(Image1),3);
            I(:,:,1) = double((Image2 - median(Image2(:)))/(quantile(Image2(:),0.998)-median(Image2(:)))); % Acceptor channel
            I(:,:,2) = double((Image1 - median(Image1(:)))/(quantile(Image1(:),0.998)-median(Image1(:)))); % Donor channel
            imshow(I,[min(I(:)),max(I(:))/10],'Parent',Args.Axes);
        end
        
        function plotSpotOverlay(app, Coords1, Coords2, AxesHandle)
            arguments
                app
                Coords1 double
                Coords2 double
                AxesHandle
            end
            hold(AxesHandle, 'on');
            plot(Coords1(:,1),Coords1(:,2),'gs','Parent',AxesHandle);
            plot(Coords2(:,1),Coords2(:,2),'ro','Parent',AxesHandle);
            hold(AxesHandle, 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % Show black images to start
            Idummy = zeros(1024,1024);
            xlim(app.OverlayOriginal, [0.5, size(Idummy, 2)+0.5]);
            ylim(app.OverlayOriginal, [0.5, size(Idummy, 1)+0.5]);
            xlim(app.OverlayCorrected, [0.5, size(Idummy, 2)+0.5]);
            ylim(app.OverlayCorrected, [0.5, size(Idummy, 1)+0.5]);
            hold(app.OverlayOriginal, 'on');
            axis(app.OverlayOriginal, 'manual');  % Prevent auto axis resizing
            hold(app.OverlayCorrected, 'on');
            axis(app.OverlayCorrected, 'manual');  % Prevent auto axis resizing
            imshow(Idummy,'Parent',app.OverlayOriginal);
            imshow(Idummy,'Parent',app.OverlayCorrected);
            % Startup prompt in log box
            addLogText(app,'Choose a donor and acceptor image file, then hit "Get Bead Map."','NewLine');

        end

        % Button pushed function: ChooseDonorButton
        function ChooseDonorButtonPushed(app, event)
            [FileName,PathName] = uigetfile('*.tif', 'Select donor file (or combined donor + acceptor file)');
            app.DonorFileName.Value = fullfile(PathName,FileName);
            addLogText(app,'Donor bead image selected.','NewLine');
            cd(PathName);
        end

        % Button pushed function: ChooseAcceptorButton
        function ChooseAcceptorButtonPushed(app, event)
            [FileName,PathName] = uigetfile('*.tif','Select acceptor file');
            app.AcceptorFileName.Value = fullfile(PathName,FileName);
            addLogText(app,'Acceptor bead image selected.','NewLine');
        end

        % Button pushed function: GetBeadMapButton
        function GetBeadMapButtonPushed(app, event)
            % Check file names
            if isempty(app.DonorFileName.Value)
                errordlg('No donor file selected!  Select a donor image and try again.');
                return
            elseif isempty(app.AcceptorFileName.Value) && ~app.DonorAndAcceptorCombined.Value
                errordlg('No acceptor file selected!  Please select an acceptor image, or check "Donor and Acceptor in Same Image."');            
                return
            end
            addLogText(app,'Starting analysis...','NewLine');
            addLogText(app,'Loading movies...','NewLine');
            AvgImage = loadBeadImages(app.DonorFileName.Value,app.AcceptorFileName.Value);

            % Find rough translational offset between images
            addLogText(app,'Getting initial translational offset...','NewLine');
            TranslationalOffset = findTranslationalOffset(AvgImage);
            AvgImage.Movie2Translated = applyTranslationalOffset(AvgImage.Movie2,TranslationalOffset);
            
            addLogText(app,'Finding bead pairs...','NewLine');
            % Find beads in both images
            Coords.Movie1 = findSpots(AvgImage.Movie1,...
                                        app.EdgePixels,...
                                        app.Threshold,...
                                        app.CircularityCutoff,...
                                        app.EllipticityCutoff,...
                                        app.SpotMethod);
            Coords.Movie2Translated = findSpots(AvgImage.Movie2Translated,...
                                        app.EdgePixels,...
                                        app.Threshold,...
                                        app.CircularityCutoff,...
                                        app.EllipticityCutoff,...
                                        app.SpotMethod);
            Coords.Movie2 = findSpots(AvgImage.Movie2,...
                                        app.EdgePixels,...
                                        app.Threshold,...
                                        app.CircularityCutoff,...
                                        app.EllipticityCutoff,...
                                        app.SpotMethod);
            
            % Get pairs of bead locations and remove translational offset to match original acceptor channel
            SpotPairs = getSpotPairs(Coords.Movie1,Coords.Movie2Translated,app.DistanceCutoff);
            SpotPairs.Coords2 = cat(2,SpotPairs.Coords2(:,1)+TranslationalOffset.X,SpotPairs.Coords2(:,2)+TranslationalOffset.Y);
            
            %Show starting images and bead locations
            showChannelOverlay(app,AvgImage.Movie1,AvgImage.Movie2,'Axes',app.OverlayOriginal);
            plotSpotOverlay(app, Coords.Movie1, Coords.Movie2,app.OverlayOriginal);

            % Construct and apply transformation to acceptor channel image
            TformInitial = fitgeotform2d(SpotPairs.Coords2,SpotPairs.Coords1,'affine');
            TformInitialInv = fitgeotform2d(SpotPairs.Coords1,SpotPairs.Coords2,'affine');
            Coords.Movie2Affine = transformPointsInverse(TformInitialInv,Coords.Movie2);
            
            % Get pairs of bead locations with initial polynomial transform and apply inverse transform to recover original acceptor coords
            SpotPairs = getSpotPairs(Coords.Movie1,Coords.Movie2Affine,app.DistanceCutoff);
            SpotPairs.Coords2 = transformPointsInverse(TformInitial,SpotPairs.Coords2);

            % Construct and apply transformation to acceptor channel image
            app.Results.Tform = fitgeotform2d(SpotPairs.Coords2,SpotPairs.Coords1,'polynomial',3);
            app.Results.TformInv = fitgeotform2d(SpotPairs.Coords1,SpotPairs.Coords2,'polynomial',3);
            ra = imref2d(size(AvgImage.Movie1));
            AvgImage.Movie2Corrected = imwarp(AvgImage.Movie2,app.Results.Tform,'OutputView',ra);
            AvgImage.Movie2Corrected(AvgImage.Movie2Corrected==0) = median(AvgImage.Movie2Corrected(:)); % Set zero values (on border) of warped image to median intensity of image
            
            % Get bead coordinates in registered acceptor, find pairs, and plot overlay with donor bead coordinates
            Coords.Movie2Corrected = findSpots(AvgImage.Movie2Corrected,app.Threshold,10,app.CircularityCutoff,app.SpotMethod);
            SpotPairsCorrected = getSpotPairs(Coords.Movie1,Coords.Movie2Corrected,app.DistanceCutoff);
            
            app.Results.AvgImage = AvgImage;

            app.Results = calculateError(app,SpotPairs,SpotPairsCorrected,app.Results);
            
            %Show final images and bead locations
            showChannelOverlay(app, AvgImage.Movie1, AvgImage.Movie2Corrected, 'Axes', app.OverlayCorrected);
            plotSpotOverlay(app, SpotPairsCorrected.Coords1,SpotPairsCorrected.Coords2, app.OverlayCorrected);

            % Enable button to save bead map file
            app.SaveBeadMapFileButton.Enable = "on";
        end

        % Value changed function: DonorAndAcceptorCombined
        function DonorAndAcceptorCombinedValueChanged(app, event)
            value = app.DonorAndAcceptorCombined.Value;
            if value
                app.ChooseAcceptorButton.Enable = "off";
                app.AcceptorFileName.Value = "";
                app.AcceptorFileName.Enable = "off";
            else
                app.ChooseAcceptorButton.Enable = "on";
                app.AcceptorFileName.Enable = "on";
            end
        end

        % Button pushed function: SaveBeadMapFileButton
        function SaveBeadMapFileButtonPushed(app, event)
            if isfield(app.Results,'Tform')
                app.Results.Coeffs = getTransformCoefficients(app.Results.Tform,app.Results.TformInv);
            else
                errordlg('No bead map transform available!  Please run a bead mapping and try again.');
            end

            [File, Path] = uiputfile('*.txt','Select where to save your map file.','Bead_mapped.txt');
            FullModel = app.Results.Coeffs.FullModel;
            if exist(fullfile(Path),"file")
                save(fullfile(Path,File),'FullModel','-ascii');
                addLogText(app,['Saved bead map file to ',fullfile(Path,File)],'NewLine');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BeadMapperSimpleUIFigure = uifigure('Visible', 'off');
            app.BeadMapperSimpleUIFigure.Name = 'Bead Mapper Simple';
            app.OverlayOriginal = uiaxes(app.BeadMapperSimpleUIFigure);
            zlabel(app.OverlayOriginal, 'Z')
            app.OverlayCorrected = uiaxes(app.BeadMapperSimpleUIFigure);
            zlabel(app.OverlayCorrected, 'Z')
            app.OverlayCorrected.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DonorAcceptorOriginalLabel = uilabel(app.BeadMapperSimpleUIFigure);
            app.DonorAcceptorOriginalLabel.Text = 'Donor + Acceptor (Original)';
            app.DonorAcceptorRegisteredLabel = uilabel(app.BeadMapperSimpleUIFigure);
            app.DonorAcceptorRegisteredLabel.Text = 'Donor + Acceptor (Registered)';
            app.ChooseDonorButton = uibutton(app.BeadMapperSimpleUIFigure, 'push');
            app.ChooseDonorButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDonorButtonPushed, true);
            app.ChooseDonorButton.Text = 'Choose...';
            app.ChooseAcceptorButton = uibutton(app.BeadMapperSimpleUIFigure, 'push');
            app.ChooseAcceptorButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseAcceptorButtonPushed, true);
            app.ChooseAcceptorButton.Text = 'Choose...';
            app.LogTextAreaLabel = uilabel(app.BeadMapperSimpleUIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogBox = uitextarea(app.BeadMapperSimpleUIFigure);
            app.DonorBeadImageLabel = uilabel(app.BeadMapperSimpleUIFigure);
            app.DonorBeadImageLabel.Tooltip = {'If using a movie with Donor + Acceptor combined, load it as Donor Bead File'};
            app.DonorBeadImageLabel.Text = 'Donor Bead Image';
            app.AcceptorBeadImageLabel = uilabel(app.BeadMapperSimpleUIFigure);
            app.AcceptorBeadImageLabel.Tooltip = {'If using a single TIF file with both Donor and Acceptor, LEAVE THIS BLANK'};
            app.AcceptorBeadImageLabel.Text = 'Acceptor Bead Image';
            app.DonorFileName = uieditfield(app.BeadMapperSimpleUIFigure, 'text');
            app.AcceptorFileName = uieditfield(app.BeadMapperSimpleUIFigure, 'text');
            app.DonorAndAcceptorCombined = uicheckbox(app.BeadMapperSimpleUIFigure);
            app.DonorAndAcceptorCombined.ValueChangedFcn = createCallbackFcn(app, @DonorAndAcceptorCombinedValueChanged, true);
            app.DonorAndAcceptorCombined.Text = 'Donor and Acceptor in Same Image';
            app.GetBeadMapButton = uibutton(app.BeadMapperSimpleUIFigure, 'push');
            app.GetBeadMapButton.ButtonPushedFcn = createCallbackFcn(app, @GetBeadMapButtonPushed, true);
            app.GetBeadMapButton.Text = 'Get Bead Map';
            app.SaveBeadMapFileButton = uibutton(app.BeadMapperSimpleUIFigure, 'push');
            app.SaveBeadMapFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBeadMapFileButtonPushed, true);
            app.SaveBeadMapFileButton.Enable = 'off';
            app.SaveBeadMapFileButton.Text = 'Save Bead Map File';
            % Show the figure after all components are created
            app.BeadMapperSimpleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeadMapperSimple

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BeadMapperSimpleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BeadMapperSimpleUIFigure)
        end
    end
end